classdef AWSimApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AWSim                           matlab.ui.Figure
        MainTabs                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        SettingsTabs                    matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        GenParatitle                    matlab.ui.control.Label
        GenParaSummary                  matlab.ui.control.Label
        LoadExistingParaButt            matlab.ui.control.Button
        RNGSeedEditFieldLabel           matlab.ui.control.Label
        RNGSeedField                    matlab.ui.control.NumericEditField
        SimulationTitleEditFieldLabel   matlab.ui.control.Label
        SimTitleField                   matlab.ui.control.EditField
        SaveFolderButt                  matlab.ui.control.Button
        SaveFolderDisp                  matlab.ui.control.TextArea
        SurvivorRoundsEditFieldLabel    matlab.ui.control.Label
        SurvRounds                      matlab.ui.control.NumericEditField
        SaveAndStart                    matlab.ui.control.Button
        LoadExistingPlayersButt         matlab.ui.control.Button
        YoumustrunSetupbeforeyoucanLabel  matlab.ui.control.Label
        makePlayerTab                   matlab.ui.container.Tab
        makePlayerTitle                 matlab.ui.control.Label
        makePlayerSummary               matlab.ui.control.Label
        ExperienceMethodPanel           matlab.ui.container.Panel
        ExpModeSwi                      matlab.ui.control.Switch
        MinimumExpSpinnerLabel          matlab.ui.control.Label
        DiscMinExp                      matlab.ui.control.Spinner
        MaximumExpSpinnerLabel          matlab.ui.control.Label
        DiscMaxExp                      matlab.ui.control.Spinner
        MaximumExpSpinner_2Label        matlab.ui.control.Label
        ContMaxExp                      matlab.ui.control.Spinner
        ContLab                         matlab.ui.control.Label
        DiscLab                         matlab.ui.control.Label
        ColorDistrobutionPanel          matlab.ui.container.Panel
        ShapeLabel                      matlab.ui.control.Label
        LuminanceLabel                  matlab.ui.control.Label
        SaturationLabel                 matlab.ui.control.Label
        ASpinner_2Label                 matlab.ui.control.Label
        LumA                            matlab.ui.control.Spinner
        BSpinner_2Label                 matlab.ui.control.Label
        LumB                            matlab.ui.control.Spinner
        ASpinnerLabel                   matlab.ui.control.Label
        SatA                            matlab.ui.control.Spinner
        BSpinnerLabel                   matlab.ui.control.Label
        SatB                            matlab.ui.control.Spinner
        duelTab                         matlab.ui.container.Tab
        duelParaTitle                   matlab.ui.control.Label
        duelParaSum                     matlab.ui.control.Label
        ExperienceModifierEditFieldLabel  matlab.ui.control.Label
        ExpModField                     matlab.ui.control.NumericEditField
        DrawToleranceEditFieldLabel     matlab.ui.control.Label
        DrawTolField                    matlab.ui.control.NumericEditField
        HighLevelWinEditFieldLabel      matlab.ui.control.Label
        HighWinFunc                     matlab.ui.control.EditField
        LowLevelWinEditFieldLabel       matlab.ui.control.Label
        LowWinFunc                      matlab.ui.control.EditField
        PointTransferFunctionsLabel     matlab.ui.control.Label
        LevelExperienceContributionEditFieldLabel  matlab.ui.control.Label
        lvlExpContFunc                  matlab.ui.control.EditField
        Funcexplain1                    matlab.ui.control.Label
        funcexplain2                    matlab.ui.control.Label
        StaticscalarvaluesLabel         matlab.ui.control.Label
        DrawEditFieldLabel              matlab.ui.control.Label
        DrawTrans                       matlab.ui.control.NumericEditField
        EqualLevelEditFieldLabel        matlab.ui.control.Label
        EqualLvlTrans                   matlab.ui.control.NumericEditField
        funcexpl3                       matlab.ui.control.Label
        killEmTab                       matlab.ui.container.Tab
        killEmParaTitlte                matlab.ui.control.Label
        killEmParasum                   matlab.ui.control.Label
        noParasLabel                    matlab.ui.control.Label
        lvlUpTab                        matlab.ui.container.Tab
        lvlUpParaTitle                  matlab.ui.control.Label
        lvlUpParaSum                    matlab.ui.control.Label
        lvlUpPointsTable                matlab.ui.control.Table
        lUPlabel                        matlab.ui.control.Label
        SafetymarginLabel               matlab.ui.control.Label
        lvlUpFOS                        matlab.ui.control.EditField
        FOSexplain                      matlab.ui.control.Label
        makeBabesTab                    matlab.ui.container.Tab
        makeBabesParaTitle              matlab.ui.control.Label
        makeBabesSum                    matlab.ui.control.Label
        ChildChanceFunctionEditFieldLabel  matlab.ui.control.Label
        kidChanceFcn                    matlab.ui.control.EditField
        kidChanceFcnexplain             matlab.ui.control.Label
        PureColorsTab                   matlab.ui.container.Tab
        PureColorParaTitle              matlab.ui.control.Label
        PureColorParaSum                matlab.ui.control.Label
        PureColorTable                  matlab.ui.control.Table
        ExperienceEditFieldLabel        matlab.ui.control.Label
        PCexp                           matlab.ui.control.NumericEditField
        PCexpExplain                    matlab.ui.control.Label
        PCtableExplain                  matlab.ui.control.Label
        PCColorDisplay                  matlab.ui.control.Table
        SettingsEditExplain             matlab.ui.control.Label
        restartButt                     matlab.ui.control.Button
        RunTab                          matlab.ui.container.Tab
        RunningGrid                     matlab.ui.container.GridLayout
        RunPermGrid                     matlab.ui.container.GridLayout
        RoundtoDisplaySliderLabel       matlab.ui.control.Label
        DispSlider                      matlab.ui.control.Slider
        RunningButtons                  matlab.ui.container.GridLayout
        Run1RoundButton                 matlab.ui.control.Button
        RunRoundsButton                 matlab.ui.control.StateButton
        RunXSpinner                     matlab.ui.control.Spinner
        RunXRoundsButton                matlab.ui.control.Button
        GridLayout3                     matlab.ui.container.GridLayout
        DispSpinner                     matlab.ui.control.Spinner
        MaxRoundEditFieldLabel          matlab.ui.control.Label
        MaxRounds                       matlab.ui.control.NumericEditField
        RunningTabs                     matlab.ui.container.TabGroup
        BPScatterTab                    matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        BPScatterAxes                   matlab.ui.control.UIAxes
        PlayersExpTab                   matlab.ui.container.Tab
        PlayersExpGRid                  matlab.ui.container.GridLayout
        TPandBPGrid                     matlab.ui.container.GridLayout
        BPatDisplayRoundEditFieldLabel  matlab.ui.control.Label
        BPdispRound                     matlab.ui.control.NumericEditField
        TotalPlayersatDispRoundEditFieldLabel  matlab.ui.control.Label
        TotalPlayersRound               matlab.ui.control.NumericEditField
        AOTgrid                         matlab.ui.container.GridLayout
        PlayersAliveatDispRoundEditFieldLabel  matlab.ui.control.Label
        PlayersAliveRound               matlab.ui.control.NumericEditField
        OriginatorsAliveEditFieldLabel  matlab.ui.control.Label
        OgsAliveRound                   matlab.ui.control.NumericEditField
        TotPlayandBPAxes                matlab.ui.control.UIAxes
        AliveOverTimeAxes               matlab.ui.control.UIAxes
        BPExpTab                        matlab.ui.container.Tab
        BPExpGrid                       matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        TotalAverageExpEditFieldLabel   matlab.ui.control.Label
        TotalExpRound                   matlab.ui.control.NumericEditField
        TotalPlayersEditFieldLabel      matlab.ui.control.Label
        TotalPlayersRound1              matlab.ui.control.NumericEditField
        SurvivorAverageExpEditFieldLabel  matlab.ui.control.Label
        SurvExpRound                    matlab.ui.control.NumericEditField
        TotalSurvivorsEditFieldLabel    matlab.ui.control.Label
        SurvPlayersRound                matlab.ui.control.NumericEditField
        BPExpAxes                       matlab.ui.control.UIAxes
        LevelsGen1sTab                  matlab.ui.container.Tab
        LvlG1Grid                       matlab.ui.container.GridLayout
        LevelAreaAxes                   matlab.ui.control.UIAxes
        OrigsBPAxes                     matlab.ui.control.UIAxes
        LvlCompTable                    matlab.ui.control.Table
        PureColorsGraphTab              matlab.ui.container.Tab
        PCGraphGrid                     matlab.ui.container.GridLayout
        PureColorsAxes                  matlab.ui.control.UIAxes
        PureColorBPDisp                 matlab.ui.control.Table
        TrackPlayersTab                 matlab.ui.container.Tab
        GimmesometimeyaLabel            matlab.ui.control.Label
        SaveTab                         matlab.ui.container.Tab
        SaveGridMain                    matlab.ui.container.GridLayout
        SaveTitle                       matlab.ui.control.Label
        SaveExplain                     matlab.ui.control.Label
        SaveLowerSpace                  matlab.ui.container.GridLayout
        SaveButtons                     matlab.ui.container.GridLayout
        BBVidButton                     matlab.ui.control.Button
        ManSaveButton                   matlab.ui.control.Button
        TrendyPicButton                 matlab.ui.control.Button
        GridLayout4                     matlab.ui.container.GridLayout
        VideoFramerateEditFieldLabel    matlab.ui.control.Label
        VidFrameRate                    matlab.ui.control.NumericEditField
        IntrolengthsecondsEditFieldLabel  matlab.ui.control.Label
        VidIntroLength                  matlab.ui.control.NumericEditField
        PicNameGrid                     matlab.ui.container.GridLayout
        TrendsPicNameEditFieldLabel     matlab.ui.control.Label
        PicName                         matlab.ui.control.EditField
        VidNameGrid                     matlab.ui.container.GridLayout
        BBVideoNameEditFieldLabel       matlab.ui.control.Label
        VidName                         matlab.ui.control.EditField
    end

    
    properties (Access = public)
        genPara; % General Parameters
        makePlayerPara; %makePlayer parameters
        duelPara; %duel parameters
        killEmPara; %killEm parameters, not used
        lvlUpPara; %lvlUp Parameters
        makeBabesPara; %makeBabes Parameters
        PureColors; %PureColor player colors
        PureColorPara; %PureColor paramters
        players; % %struct of players
        bigData; %struct of general info
        currRound = 0; %current round of simulation
        dispRound = 0; %round to display on charts
        movie; %moviewriter object
        roundLines = struct('TotPlayandBPAxes',[], 'AliveOverTimeAxes',...
            [],'BPExpAxes',[],'LevelAreaAxes',[], 'OrigsBPAxes',[],...
            'PureColorsAxes',[]); %holder for lines of current round display
        sliderTimer; %timer to update the slider?
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            applyDefaults(app, 0); %try it from outside the app
        end

        % Callback function: ContMaxExp, DiscMaxExp, DiscMinExp, 
        % DrawTolField, DrawTrans, EqualLvlTrans, ExpModField, 
        % ExpModeSwi, HighWinFunc, LoadExistingParaButt, 
        % LoadExistingPlayersButt, LowWinFunc, LumA, LumB, PCexp, 
        % PureColorTable, RNGSeedField, SatA, SatB, SaveAndStart, 
        % SaveFolderButt, SettingsTabs, SimTitleField, SurvRounds, 
        % kidChanceFcn, lvlExpContFunc, lvlUpFOS, lvlUpPointsTable, 
        % restartButt
        function SetupCalls(app, event)
            settingsCallbacks(app,event); %pass it to an outside func
        end

        % Callback function: AWSim, BBVidButton, DispSlider, 
        % DispSlider, DispSpinner, DispSpinner, ManSaveButton, 
        % PicName, Run1RoundButton, RunRoundsButton, 
        % RunXRoundsButton, TrendyPicButton, VidFrameRate, 
        % VidIntroLength, VidName
        function runCalls(app, event)
            runningCallbacks(app,event); %pass it to outside function
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AWSim = uifigure('Visible', 'off');
            app.AWSim.Name = 'Accel World Agent Simulator';
            app.AWSim.CloseRequestFcn = createCallbackFcn(app, @runCalls, true);
            app.MainTabs = uitabgroup(app.AWSim);
            app.SetupTab = uitab(app.MainTabs);
            app.SetupTab.Title = 'Setup';
            app.SettingsTabs = uitabgroup(app.SetupTab);
            app.SettingsTabs.SelectionChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.GeneralTab = uitab(app.SettingsTabs);
            app.GeneralTab.Title = 'General';
            app.GenParatitle = uilabel(app.GeneralTab);
            app.GenParatitle.Text = 'General Parameters';
            app.GenParaSummary = uilabel(app.GeneralTab);
            app.GenParaSummary.Text = 'Parameters Affecting the Whole Simulation';
            app.LoadExistingParaButt = uibutton(app.GeneralTab, 'push');
            app.LoadExistingParaButt.ButtonPushedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.LoadExistingParaButt.Text = 'Load Existing Sim Parameters';
            app.RNGSeedEditFieldLabel = uilabel(app.GeneralTab);
            app.RNGSeedEditFieldLabel.Text = 'RNG Seed';
            app.RNGSeedField = uieditfield(app.GeneralTab, 'numeric');
            app.RNGSeedField.Limits = [0 4294967296];
            app.RNGSeedField.RoundFractionalValues = 'on';
            app.RNGSeedField.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.RNGSeedField.Value = 2039;
            app.SimulationTitleEditFieldLabel = uilabel(app.GeneralTab);
            app.SimulationTitleEditFieldLabel.Text = 'Simulation Title';
            app.SimTitleField = uieditfield(app.GeneralTab, 'text');
            app.SimTitleField.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.SaveFolderButt = uibutton(app.GeneralTab, 'push');
            app.SaveFolderButt.ButtonPushedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.SaveFolderButt.Text = 'Folder to Save to';
            app.SaveFolderDisp = uitextarea(app.GeneralTab);
            app.SaveFolderDisp.Editable = 'off';
            app.SaveFolderDisp.Tooltip = {'Folder to save the simulation data to'};
            app.SurvivorRoundsEditFieldLabel = uilabel(app.GeneralTab);
            app.SurvivorRoundsEditFieldLabel.Text = 'Survivor Rounds';
            app.SurvRounds = uieditfield(app.GeneralTab, 'numeric');
            app.SurvRounds.Limits = [0 Inf];
            app.SurvRounds.RoundFractionalValues = 'on';
            app.SurvRounds.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.SurvRounds.Value = 30;
            app.SaveAndStart = uibutton(app.GeneralTab, 'push');
            app.SaveAndStart.ButtonPushedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.SaveAndStart.Text = 'Save and Start!';
            app.LoadExistingPlayersButt = uibutton(app.GeneralTab, 'push');
            app.LoadExistingPlayersButt.ButtonPushedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.LoadExistingPlayersButt.Text = 'Load Existing Sim Players';
            app.YoumustrunSetupbeforeyoucanLabel = uilabel(app.GeneralTab);
            app.YoumustrunSetupbeforeyoucanLabel.Text = 'You must run Setup before you can ';
            app.makePlayerTab = uitab(app.SettingsTabs);
            app.makePlayerTab.Title = 'makePlayer';
            app.makePlayerTitle = uilabel(app.makePlayerTab);
            app.makePlayerTitle.Text = 'makePlayer Parameters';
            app.makePlayerSummary = uilabel(app.makePlayerTab);
            app.makePlayerSummary.Text = 'Parameters related to creating new general players.';
            app.ExperienceMethodPanel = uipanel(app.makePlayerTab);
            app.ExperienceMethodPanel.Title = 'Experience Method';
            app.ExpModeSwi = uiswitch(app.ExperienceMethodPanel, 'slider');
            app.ExpModeSwi.Items = {'Continuous', 'Discrete'};
            app.ExpModeSwi.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.ExpModeSwi.Value = 'Continuous';
            app.MinimumExpSpinnerLabel = uilabel(app.ExperienceMethodPanel);
            app.MinimumExpSpinnerLabel.Text = 'Minimum Exp';
            app.DiscMinExp = uispinner(app.ExperienceMethodPanel);
            app.DiscMinExp.Limits = [0 Inf];
            app.DiscMinExp.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.DiscMinExp.Tooltip = {'Lower bound of random integer range'};
            app.MaximumExpSpinnerLabel = uilabel(app.ExperienceMethodPanel);
            app.MaximumExpSpinnerLabel.Text = 'Maximum Exp';
            app.DiscMaxExp = uispinner(app.ExperienceMethodPanel);
            app.DiscMaxExp.Limits = [0 Inf];
            app.DiscMaxExp.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.DiscMaxExp.Tooltip = {'Upper Bound of random integer range'};
            app.DiscMaxExp.Value = 10;
            app.MaximumExpSpinner_2Label = uilabel(app.ExperienceMethodPanel);
            app.MaximumExpSpinner_2Label.Text = 'Maximum Exp';
            app.ContMaxExp = uispinner(app.ExperienceMethodPanel);
            app.ContMaxExp.Limits = [0 Inf];
            app.ContMaxExp.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.ContMaxExp.Tooltip = {'Coefficient of random number [0'; '1] for experience'};
            app.ContMaxExp.Value = 10;
            app.ContLab = uilabel(app.ExperienceMethodPanel);
            app.ContLab.Text = 'Continuous:';
            app.DiscLab = uilabel(app.ExperienceMethodPanel);
            app.DiscLab.Text = 'Discrete:';
            app.ColorDistrobutionPanel = uipanel(app.makePlayerTab);
            app.ColorDistrobutionPanel.Title = 'Color Distrobution';
            app.ShapeLabel = uilabel(app.ColorDistrobutionPanel);
            app.ShapeLabel.Text = {'Beta Distrobution '; 'Shape Parameters'};
            app.LuminanceLabel = uilabel(app.ColorDistrobutionPanel);
            app.LuminanceLabel.Text = 'Luminance (HSL)';
            app.SaturationLabel = uilabel(app.ColorDistrobutionPanel);
            app.SaturationLabel.Text = 'Saturation (HSL)';
            app.ASpinner_2Label = uilabel(app.ColorDistrobutionPanel);
            app.ASpinner_2Label.Text = 'A:';
            app.LumA = uispinner(app.ColorDistrobutionPanel);
            app.LumA.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.BSpinner_2Label = uilabel(app.ColorDistrobutionPanel);
            app.BSpinner_2Label.Text = 'B:';
            app.LumB = uispinner(app.ColorDistrobutionPanel);
            app.LumB.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.ASpinnerLabel = uilabel(app.ColorDistrobutionPanel);
            app.ASpinnerLabel.Text = 'A:';
            app.SatA = uispinner(app.ColorDistrobutionPanel);
            app.SatA.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.BSpinnerLabel = uilabel(app.ColorDistrobutionPanel);
            app.BSpinnerLabel.Text = 'B:';
            app.SatB = uispinner(app.ColorDistrobutionPanel);
            app.SatB.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.duelTab = uitab(app.SettingsTabs);
            app.duelTab.Title = 'duel';
            app.duelParaTitle = uilabel(app.duelTab);
            app.duelParaTitle.Text = 'duel Parameters';
            app.duelParaSum = uilabel(app.duelTab);
            app.duelParaSum.Text = 'Parameters related to actual battles between two players';
            app.ExperienceModifierEditFieldLabel = uilabel(app.duelTab);
            app.ExperienceModifierEditFieldLabel.Text = 'Experience Modifier';
            app.ExpModField = uieditfield(app.duelTab, 'numeric');
            app.ExpModField.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.DrawToleranceEditFieldLabel = uilabel(app.duelTab);
            app.DrawToleranceEditFieldLabel.Text = 'Draw Tolerance';
            app.DrawTolField = uieditfield(app.duelTab, 'numeric');
            app.DrawTolField.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.HighLevelWinEditFieldLabel = uilabel(app.duelTab);
            app.HighLevelWinEditFieldLabel.Text = 'High Level Win';
            app.HighWinFunc = uieditfield(app.duelTab, 'text');
            app.HighWinFunc.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.HighWinFunc.Value = 'round(10/(1+lvlDiff))';
            app.LowLevelWinEditFieldLabel = uilabel(app.duelTab);
            app.LowLevelWinEditFieldLabel.Text = 'Low Level Win';
            app.LowWinFunc = uieditfield(app.duelTab, 'text');
            app.LowWinFunc.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.LowWinFunc.Value = '(10*(1+lvlDiff))';
            app.PointTransferFunctionsLabel = uilabel(app.duelTab);
            app.PointTransferFunctionsLabel.Text = 'Point Transfer Functions';
            app.LevelExperienceContributionEditFieldLabel = uilabel(app.duelTab);
            app.LevelExperienceContributionEditFieldLabel.Text = {'Level Experience '; 'Contribution'};
            app.lvlExpContFunc = uieditfield(app.duelTab, 'text');
            app.lvlExpContFunc.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.lvlExpContFunc.Value = 'lvl.^2';
            app.Funcexplain1 = uilabel(app.duelTab);
            app.Funcexplain1.Text = 'Function of ''lvl'', please enter a function using standard operations';
            app.funcexplain2 = uilabel(app.duelTab);
            app.funcexplain2.Text = 'Functions of ''lvlDiff'', please enter a function uses MATLAB operators';
            app.StaticscalarvaluesLabel = uilabel(app.duelTab);
            app.StaticscalarvaluesLabel.Text = 'Static scalar values:';
            app.DrawEditFieldLabel = uilabel(app.duelTab);
            app.DrawEditFieldLabel.Text = 'Draw';
            app.DrawTrans = uieditfield(app.duelTab, 'numeric');
            app.DrawTrans.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.EqualLevelEditFieldLabel = uilabel(app.duelTab);
            app.EqualLevelEditFieldLabel.Text = 'Equal Level';
            app.EqualLvlTrans = uieditfield(app.duelTab, 'numeric');
            app.EqualLvlTrans.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.EqualLvlTrans.Value = 10;
            app.funcexpl3 = uilabel(app.duelTab);
            app.funcexpl3.Text = '(''@(lvlDiff) f(lvlDiff)'')';
            app.killEmTab = uitab(app.SettingsTabs);
            app.killEmTab.Title = 'killEm';
            app.killEmParaTitlte = uilabel(app.killEmTab);
            app.killEmParaTitlte.Text = 'killEm Parameters';
            app.killEmParasum = uilabel(app.killEmTab);
            app.killEmParasum.Text = 'Parameters related to eliminating dead players and managing Live- and deadlist.';
            app.noParasLabel = uilabel(app.killEmTab);
            app.noParasLabel.Text = {'There''s nothing here yet, it''s here as a formality in case we do actually need '; 'to parameterize something later.'};
            app.lvlUpTab = uitab(app.SettingsTabs);
            app.lvlUpTab.Title = 'lvlUp';
            app.lvlUpParaTitle = uilabel(app.lvlUpTab);
            app.lvlUpParaTitle.Text = 'lvlUp Parameters';
            app.lvlUpParaSum = uilabel(app.lvlUpTab);
            app.lvlUpParaSum.Text = 'Parameters related to creating levelling up and advancing to higher stages of Brain Burst';
            app.lvlUpPointsTable = uitable(app.lvlUpTab);
            app.lvlUpPointsTable.ColumnName = {'From Level'; 'Points Needed'};
            app.lvlUpPointsTable.RowName = {''};
            app.lvlUpPointsTable.CellEditCallback = createCallbackFcn(app, @SetupCalls, true);
            app.lvlUpPointsTable.Tooltip = {'Pointed Needed to increase level'};
            app.lUPlabel = uilabel(app.lvlUpTab);
            app.lUPlabel.Text = 'Pointed Needed to Level Up';
            app.SafetymarginLabel = uilabel(app.lvlUpTab);
            app.SafetymarginLabel.Text = 'Safety margin:';
            app.lvlUpFOS = uieditfield(app.lvlUpTab, 'text');
            app.lvlUpFOS.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.FOSexplain = uilabel(app.lvlUpTab);
            app.FOSexplain.Text = {'Function (no inputs), please use '; 'MATLAB operators '; '(ie rand and scalars)'};
            app.makeBabesTab = uitab(app.SettingsTabs);
            app.makeBabesTab.Title = 'makeBabes';
            app.makeBabesParaTitle = uilabel(app.makeBabesTab);
            app.makeBabesParaTitle.Text = 'makeBabes Parameters';
            app.makeBabesSum = uilabel(app.makeBabesTab);
            app.makeBabesSum.Text = 'Parameters related to creating 2+ generation players';
            app.ChildChanceFunctionEditFieldLabel = uilabel(app.makeBabesTab);
            app.ChildChanceFunctionEditFieldLabel.Text = 'Child Chance Function';
            app.kidChanceFcn = uieditfield(app.makeBabesTab, 'text');
            app.kidChanceFcn.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.kidChanceFcn.Value = 'randi(floor(round/30))';
            app.kidChanceFcnexplain = uilabel(app.makeBabesTab);
            app.kidChanceFcnexplain.Text = 'Function Handle of (round, lvl, gen) [current round number, parent level and generation]';
            app.PureColorsTab = uitab(app.SettingsTabs);
            app.PureColorsTab.Title = 'Pure Colors';
            app.PureColorParaTitle = uilabel(app.PureColorsTab);
            app.PureColorParaTitle.Text = 'Pure Color Parameters';
            app.PureColorParaSum = uilabel(app.PureColorsTab);
            app.PureColorParaSum.Text = {'Parameters related to creating the Pure Colors, high powered players we can easily '; 'follow thoughout the simulation.'};
            app.PureColorTable = uitable(app.PureColorsTab);
            app.PureColorTable.ColumnName = {'Color'; 'Hue'; 'Sat'; 'Lum'};
            app.PureColorTable.RowName = {};
            app.PureColorTable.CellEditCallback = createCallbackFcn(app, @SetupCalls, true);
            app.ExperienceEditFieldLabel = uilabel(app.PureColorsTab);
            app.ExperienceEditFieldLabel.Text = 'Experience';
            app.PCexp = uieditfield(app.PureColorsTab, 'numeric');
            app.PCexp.ValueChangedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.PCexp.Value = 11;
            app.PCexpExplain = uilabel(app.PureColorsTab);
            app.PCexpExplain.Text = {'Specify Exp to allow '; 'Pure Colors to be more '; 'powerful (and more likely'; 'to survive) than '; 'normal players'};
            app.PCtableExplain = uilabel(app.PureColorsTab);
            app.PCtableExplain.Text = 'Table of Pure Colors (HSL colors)';
            app.PCColorDisplay = uitable(app.PureColorsTab);
            app.PCColorDisplay.ColumnName = {'Color'};
            app.PCColorDisplay.RowName = {};
            app.PCColorDisplay.ColumnEditable = false;
            app.SettingsEditExplain = uilabel(app.SetupTab);
            app.SettingsEditExplain.Visible = 'off';
            app.SettingsEditExplain.Text = {'Once you have saved and created player structs you cannot edit Parameters, only run the simulation.  '; 'If you want to change parameters please make a New Simulation.'};
            app.restartButt = uibutton(app.SetupTab, 'push');
            app.restartButt.ButtonPushedFcn = createCallbackFcn(app, @SetupCalls, true);
            app.restartButt.Enable = 'off';
            app.restartButt.Visible = 'off';
            app.restartButt.Text = 'Restart Simulation';
            app.RunTab = uitab(app.MainTabs);
            app.RunTab.Title = 'Run';
            app.RunningGrid = uigridlayout(app.RunTab);
            app.RunPermGrid = uigridlayout(app.RunningGrid);
            app.RoundtoDisplaySliderLabel = uilabel(app.RunPermGrid);
            app.RoundtoDisplaySliderLabel.Text = {'Round to'; 'Display'};
            app.DispSlider = uislider(app.RunPermGrid);
            app.DispSlider.Limits = [0 0.1];
            app.DispSlider.ValueChangedFcn = createCallbackFcn(app, @runCalls, true);
            app.DispSlider.ValueChangingFcn = createCallbackFcn(app, @runCalls, true);
            app.RunningButtons = uigridlayout(app.RunPermGrid);
            app.Run1RoundButton = uibutton(app.RunningButtons, 'push');
            app.Run1RoundButton.ButtonPushedFcn = createCallbackFcn(app, @runCalls, true);
            app.Run1RoundButton.Text = 'Run 1 Round';
            app.RunRoundsButton = uibutton(app.RunningButtons, 'state');
            app.RunRoundsButton.ValueChangedFcn = createCallbackFcn(app, @runCalls, true);
            app.RunRoundsButton.Text = 'Run Rounds';
            app.RunXSpinner = uispinner(app.RunningButtons);
            app.RunXSpinner.Limits = [1 Inf];
            app.RunXSpinner.RoundFractionalValues = 'on';
            app.RunXSpinner.Value = 10;
            app.RunXRoundsButton = uibutton(app.RunningButtons, 'push');
            app.RunXRoundsButton.ButtonPushedFcn = createCallbackFcn(app, @runCalls, true);
            app.RunXRoundsButton.Text = 'RunRounds->';
            app.GridLayout3 = uigridlayout(app.RunPermGrid);
            app.DispSpinner = uispinner(app.GridLayout3);
            app.DispSpinner.ValueChangingFcn = createCallbackFcn(app, @runCalls, true);
            app.DispSpinner.Limits = [0 0.1];
            app.DispSpinner.RoundFractionalValues = 'on';
            app.DispSpinner.ValueChangedFcn = createCallbackFcn(app, @runCalls, true);
            app.MaxRoundEditFieldLabel = uilabel(app.GridLayout3);
            app.MaxRoundEditFieldLabel.Text = 'Max Round';
            app.MaxRounds = uieditfield(app.GridLayout3, 'numeric');
            app.MaxRounds.Limits = [0 Inf];
            app.MaxRounds.Editable = 'off';
            app.RunningTabs = uitabgroup(app.RunningGrid);
            app.BPScatterTab = uitab(app.RunningTabs);
            app.BPScatterTab.Title = 'BP Scatter';
            app.GridLayout = uigridlayout(app.BPScatterTab);
            app.BPScatterAxes = uiaxes(app.GridLayout);
            title(app.BPScatterAxes, 'Title')
            xlabel(app.BPScatterAxes, 'X')
            ylabel(app.BPScatterAxes, 'Y')
            app.PlayersExpTab = uitab(app.RunningTabs);
            app.PlayersExpTab.Title = 'Players & Exp';
            app.PlayersExpGRid = uigridlayout(app.PlayersExpTab);
            app.TPandBPGrid = uigridlayout(app.PlayersExpGRid);
            app.BPatDisplayRoundEditFieldLabel = uilabel(app.TPandBPGrid);
            app.BPatDisplayRoundEditFieldLabel.Text = 'BP at Display Round';
            app.BPdispRound = uieditfield(app.TPandBPGrid, 'numeric');
            app.BPdispRound.Editable = 'off';
            app.TotalPlayersatDispRoundEditFieldLabel = uilabel(app.TPandBPGrid);
            app.TotalPlayersatDispRoundEditFieldLabel.Text = 'Total Players at Disp Round';
            app.TotalPlayersRound = uieditfield(app.TPandBPGrid, 'numeric');
            app.TotalPlayersRound.Editable = 'off';
            app.AOTgrid = uigridlayout(app.PlayersExpGRid);
            app.PlayersAliveatDispRoundEditFieldLabel = uilabel(app.AOTgrid);
            app.PlayersAliveatDispRoundEditFieldLabel.Text = 'Players Alive at Disp Round';
            app.PlayersAliveRound = uieditfield(app.AOTgrid, 'numeric');
            app.PlayersAliveRound.Editable = 'off';
            app.OriginatorsAliveEditFieldLabel = uilabel(app.AOTgrid);
            app.OriginatorsAliveEditFieldLabel.Text = 'Originators Alive';
            app.OgsAliveRound = uieditfield(app.AOTgrid, 'numeric');
            app.OgsAliveRound.Editable = 'off';
            app.TotPlayandBPAxes = uiaxes(app.PlayersExpGRid);
            title(app.TotPlayandBPAxes, 'Title')
            xlabel(app.TotPlayandBPAxes, 'X')
            ylabel(app.TotPlayandBPAxes, 'Y')
            app.AliveOverTimeAxes = uiaxes(app.PlayersExpGRid);
            title(app.AliveOverTimeAxes, 'Title')
            xlabel(app.AliveOverTimeAxes, 'X')
            ylabel(app.AliveOverTimeAxes, 'Y')
            app.BPExpTab = uitab(app.RunningTabs);
            app.BPExpTab.Title = 'BP & Exp';
            app.BPExpGrid = uigridlayout(app.BPExpTab);
            app.GridLayout2 = uigridlayout(app.BPExpGrid);
            app.TotalAverageExpEditFieldLabel = uilabel(app.GridLayout2);
            app.TotalAverageExpEditFieldLabel.Text = 'Total Average Exp';
            app.TotalExpRound = uieditfield(app.GridLayout2, 'numeric');
            app.TotalExpRound.Editable = 'off';
            app.TotalPlayersEditFieldLabel = uilabel(app.GridLayout2);
            app.TotalPlayersEditFieldLabel.Text = 'Total Players';
            app.TotalPlayersRound1 = uieditfield(app.GridLayout2, 'numeric');
            app.TotalPlayersRound1.Editable = 'off';
            app.SurvivorAverageExpEditFieldLabel = uilabel(app.GridLayout2);
            app.SurvivorAverageExpEditFieldLabel.Text = 'Survivor Average Exp';
            app.SurvExpRound = uieditfield(app.GridLayout2, 'numeric');
            app.SurvExpRound.Editable = 'off';
            app.TotalSurvivorsEditFieldLabel = uilabel(app.GridLayout2);
            app.TotalSurvivorsEditFieldLabel.Text = 'Total Survivors';
            app.SurvPlayersRound = uieditfield(app.GridLayout2, 'numeric');
            app.SurvPlayersRound.Editable = 'off';
            app.BPExpAxes = uiaxes(app.BPExpGrid);
            title(app.BPExpAxes, 'Title')
            xlabel(app.BPExpAxes, 'X')
            ylabel(app.BPExpAxes, 'Y')
            app.LevelsGen1sTab = uitab(app.RunningTabs);
            app.LevelsGen1sTab.Title = 'Levels & Gen1s';
            app.LvlG1Grid = uigridlayout(app.LevelsGen1sTab);
            app.LevelAreaAxes = uiaxes(app.LvlG1Grid);
            title(app.LevelAreaAxes, 'Title')
            xlabel(app.LevelAreaAxes, 'X')
            ylabel(app.LevelAreaAxes, 'Y')
            app.OrigsBPAxes = uiaxes(app.LvlG1Grid);
            title(app.OrigsBPAxes, 'Title')
            xlabel(app.OrigsBPAxes, 'X')
            ylabel(app.OrigsBPAxes, 'Y')
            app.LvlCompTable = uitable(app.LvlG1Grid);
            app.LvlCompTable.ColumnName = {'Lvl 1'; 'Lvl 2'; 'Lvl 3'; 'Lvl 4'; 'Lvl 5'; 'Lvl 6'; 'Lvl 7'; 'Lvl 8'; 'Lvl 9'; 'Lvl 10'};
            app.LvlCompTable.RowName = {'% Pop'; ''};
            app.LvlCompTable.ColumnSortable = false;
            app.LvlCompTable.ColumnEditable = false;
            app.PureColorsGraphTab = uitab(app.RunningTabs);
            app.PureColorsGraphTab.Title = 'Pure Colors';
            app.PCGraphGrid = uigridlayout(app.PureColorsGraphTab);
            app.PureColorsAxes = uiaxes(app.PCGraphGrid);
            title(app.PureColorsAxes, 'Title')
            xlabel(app.PureColorsAxes, 'X')
            ylabel(app.PureColorsAxes, 'Y')
            app.PureColorsAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PureColorBPDisp = uitable(app.PCGraphGrid);
            app.PureColorBPDisp.ColumnName = {'Red'; 'Yellow'; 'Green'; 'Blue'; 'Purple'; 'White'};
            app.PureColorBPDisp.RowName = {'BP@Round'};
            app.PureColorBPDisp.ColumnSortable = false;
            app.PureColorBPDisp.ColumnEditable = false;
            app.TrackPlayersTab = uitab(app.RunningTabs);
            app.TrackPlayersTab.Title = 'Track Players';
            app.GimmesometimeyaLabel = uilabel(app.TrackPlayersTab);
            app.GimmesometimeyaLabel.Text = 'Gimme some time, ya?';
            app.SaveTab = uitab(app.RunningTabs);
            app.SaveTab.Title = 'Save';
            app.SaveGridMain = uigridlayout(app.SaveTab);
            app.SaveTitle = uilabel(app.SaveGridMain);
            app.SaveTitle.Text = 'Save the Simulation';
            app.SaveExplain = uilabel(app.SaveGridMain);
            app.SaveExplain.Text = {'Whenever you close the application or restart the simulation, the Player data and the history'; 'will be saved automatically.  Here, you can manually save the data and additionally create '; 'pictures of the graphs for easy sharing and convert the BP Scatter plot to a video/GIF.'; ''; 'In order for the files to automatically display, they must be named ''trendsPic'' and '; '''BBoverTime'' respectively.  The files are PNG and mp4 format.'};
            app.SaveLowerSpace = uigridlayout(app.SaveGridMain);
            app.SaveButtons = uigridlayout(app.SaveLowerSpace);
            app.BBVidButton = uibutton(app.SaveButtons, 'push');
            app.BBVidButton.ButtonPushedFcn = createCallbackFcn(app, @runCalls, true);
            app.BBVidButton.Text = 'Create BB Video';
            app.ManSaveButton = uibutton(app.SaveButtons, 'push');
            app.ManSaveButton.ButtonPushedFcn = createCallbackFcn(app, @runCalls, true);
            app.ManSaveButton.Text = 'Manually Save Data';
            app.TrendyPicButton = uibutton(app.SaveButtons, 'push');
            app.TrendyPicButton.ButtonPushedFcn = createCallbackFcn(app, @runCalls, true);
            app.TrendyPicButton.Text = 'Create Trends Picture';
            app.GridLayout4 = uigridlayout(app.SaveLowerSpace);
            app.VideoFramerateEditFieldLabel = uilabel(app.GridLayout4);
            app.VideoFramerateEditFieldLabel.Text = 'Video Framerate';
            app.VidFrameRate = uieditfield(app.GridLayout4, 'numeric');
            app.VidFrameRate.Limits = [0 1000];
            app.VidFrameRate.ValueChangedFcn = createCallbackFcn(app, @runCalls, true);
            app.VidFrameRate.Value = 5;
            app.IntrolengthsecondsEditFieldLabel = uilabel(app.GridLayout4);
            app.IntrolengthsecondsEditFieldLabel.Text = 'Intro length (seconds)';
            app.VidIntroLength = uieditfield(app.GridLayout4, 'numeric');
            app.VidIntroLength.Limits = [0 60];
            app.VidIntroLength.ValueDisplayFormat = '%3.2f';
            app.VidIntroLength.ValueChangedFcn = createCallbackFcn(app, @runCalls, true);
            app.VidIntroLength.Value = 1;
            app.PicNameGrid = uigridlayout(app.SaveLowerSpace);
            app.TrendsPicNameEditFieldLabel = uilabel(app.PicNameGrid);
            app.TrendsPicNameEditFieldLabel.Text = 'Trends Pic Name';
            app.PicName = uieditfield(app.PicNameGrid, 'text');
            app.PicName.ValueChangedFcn = createCallbackFcn(app, @runCalls, true);
            app.VidNameGrid = uigridlayout(app.SaveLowerSpace);
            app.BBVideoNameEditFieldLabel = uilabel(app.VidNameGrid);
            app.BBVideoNameEditFieldLabel.Text = 'BB Video Name';
            app.VidName = uieditfield(app.VidNameGrid, 'text');
            app.VidName.ValueChangedFcn = createCallbackFcn(app, @runCalls, true);
            % Show the figure after all components are created
            app.AWSim.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AWSimApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AWSim)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AWSim)
        end
    end
end